classdef IIWAapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        KUKAIiwaLabel  matlab.ui.control.Label
        Image          matlab.ui.control.Image
        q7Slider       matlab.ui.control.Slider
        q7SliderLabel  matlab.ui.control.Label
        q6Slider       matlab.ui.control.Slider
        q6SliderLabel  matlab.ui.control.Label
        q5Slider       matlab.ui.control.Slider
        q5SliderLabel  matlab.ui.control.Label
        q4Slider       matlab.ui.control.Slider
        q4SliderLabel  matlab.ui.control.Label
        q3Slider       matlab.ui.control.Slider
        q3SliderLabel  matlab.ui.control.Label
        q2Slider       matlab.ui.control.Slider
        q2SliderLabel  matlab.ui.control.Label
        q1Slider       matlab.ui.control.Slider
        q1SliderLabel  matlab.ui.control.Label
        JOGGINGLabel   matlab.ui.control.Label
        ZButton_2      matlab.ui.control.Button
        ZButton        matlab.ui.control.Button
        YButton_2      matlab.ui.control.Button
        YButton        matlab.ui.control.Button
        XButton_2      matlab.ui.control.Button
        XButton        matlab.ui.control.Button
    end

    
    properties (Access = public)
        ModelIiwa % Description
        
        iiwaStartingQ = zeros(1,7) % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ModelIiwa = iiwa
            app.ModelIiwa.model.base = app.ModelIiwa.model.base*transl([-0.3,-0.3,0.79]);
            app.ModelIiwa.model.animate(app.iiwaStartingQ);
            axis equal;
            drawnow();
            
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            q = app.ModelIiwa.model.getpos;
            tr = app.ModelIiwa.model.fkine(q);
            tr(1,4) = tr(1,4) + 0.1;
            newQ = app.ModelIiwa.model.ikcon(tr,q);
            app.ModelIiwa.model.animate(newQ);
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            q = app.ModelIiwa.model.getpos;
            tr = app.ModelIiwa.model.fkine(q);
            tr(1,4) = tr(1,4) - 0.1;
            newQ = app.ModelIiwa.model.ikcon(tr,q);
            app.ModelIiwa.model.animate(newQ); 
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            q = app.ModelIiwa.model.getpos;
            tr = app.ModelIiwa.model.fkine(q);
            tr(2,4) = tr(2,4) + 0.1;
            newQ = app.ModelIiwa.model.ikcon(tr,q);
            app.ModelIiwa.model.animate(newQ); 
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            q = app.ModelIiwa.model.getpos;
            tr = app.ModelIiwa.model.fkine(q);
            tr(2,4) = tr(2,4) - 0.1;
            newQ = app.ModelIiwa.model.ikcon(tr,q);
            app.ModelIiwa.model.animate(newQ);
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            q = app.ModelIiwa.model.getpos;
            tr = app.ModelIiwa.model.fkine(q);
            tr(3,4) = tr(3,4) + 0.1;
            newQ = app.ModelIiwa.model.ikcon(tr,q);
            app.ModelIiwa.model.animate(newQ);
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            q = app.ModelIiwa.model.getpos;
            tr = app.ModelIiwa.model.fkine(q);
            tr(3,4) = tr(3,4) - 0.1;
            newQ = app.ModelIiwa.model.ikcon(tr,q);
            app.ModelIiwa.model.animate(newQ);
        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            value = app.q1Slider.Value;
            link1 = deg2rad(value);
            currentQ = app.ModelIiwa.model.getpos();
            app.ModelIiwa.model.animate([link1,currentQ(1,2),currentQ(1,3),currentQ(1,4),currentQ(1,5),currentQ(1,6),currentQ(1,7)]);
                
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            value = app.q2Slider.Value;
            value = app.q2Slider.Value;
            link2 = deg2rad(value);
            currentQ = app.ModelIiwa.model.getpos();
            app.ModelIiwa.model.animate([currentQ(1,1),link2,currentQ(1,3),currentQ(1,4),currentQ(1,5),currentQ(1,6),currentQ(1,7)]);
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            value = app.q3Slider.Value;
             link3 = deg2rad(value);
            currentQ = app.ModelIiwa.model.getpos();
            app.ModelIiwa.model.animate([currentQ(1,1),currentQ(1,2),link3,currentQ(1,4),currentQ(1,5),currentQ(1,6),currentQ(1,7)]);
        end

        % Value changed function: q4Slider
        function q4SliderValueChanged(app, event)
            value = app.q4Slider.Value;
            link4 = deg2rad(value);
            currentQ = app.ModelIiwa.model.getpos();
            app.ModelIiwa.model.animate([currentQ(1,1),currentQ(1,2),currentQ(1,3),link4,currentQ(1,5),currentQ(1,6),currentQ(1,7)]);
        end

        % Value changed function: q5Slider
        function q5SliderValueChanged(app, event)
            value = app.q5Slider.Value;
            link5 = deg2rad(value);
            currentQ = app.ModelIiwa.model.getpos();
            app.ModelIiwa.model.animate([currentQ(1,1),currentQ(1,2),currentQ(1,3),currentQ(1,4),link5,currentQ(1,6),currentQ(1,7)]);
        end

        % Value changed function: q6Slider
        function q6SliderValueChanged(app, event)
            value = app.q6Slider.Value;
            link6 = deg2rad(value);
            currentQ = app.ModelIiwa.model.getpos();
            app.ModelIiwa.model.animate([currentQ(1,1),currentQ(1,2),currentQ(1,3),currentQ(1,4),currentQ(1,5),link6,currentQ(1,7)]);
        end

        % Value changed function: q7Slider
        function q7SliderValueChanged(app, event)
            value = app.q7Slider.Value;
            value = app.q7Slider.Value;
            link7 = deg2rad(value);
            currentQ = app.ModelIiwa.model.getpos();
            app.ModelIiwa.model.animate([currentQ(1,1),currentQ(1,2),currentQ(1,3),currentQ(1,4),currentQ(1,5),currentQ(1,6),link7]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X+';
            app.XButton_2 = uibutton(app.UIFigure, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = 'X-';
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Y+';
            app.YButton_2 = uibutton(app.UIFigure, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = 'Y-';
            app.ZButton = uibutton(app.UIFigure, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = 'Z+';
            app.ZButton_2 = uibutton(app.UIFigure, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = 'Z-';
            app.JOGGINGLabel = uilabel(app.UIFigure);
            app.JOGGINGLabel.Text = 'JOGGING';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-170 170];
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [-120 120];
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [-170 170];
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.Limits = [-120 120];
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @q4SliderValueChanged, true);
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.Limits = [-170 170];
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @q5SliderValueChanged, true);
            app.q6SliderLabel = uilabel(app.UIFigure);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.UIFigure);
            app.q6Slider.Limits = [-120 120];
            app.q6Slider.ValueChangedFcn = createCallbackFcn(app, @q6SliderValueChanged, true);
            app.q7SliderLabel = uilabel(app.UIFigure);
            app.q7SliderLabel.Text = 'q7';
            app.q7Slider = uislider(app.UIFigure);
            app.q7Slider.Limits = [-175 175];
            app.q7Slider.ValueChangedFcn = createCallbackFcn(app, @q7SliderValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'iiwa img.jpg';
            app.KUKAIiwaLabel = uilabel(app.UIFigure);
            app.KUKAIiwaLabel.Text = 'KUKA Iiwa';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IIWAapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end