classdef UR3app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UR3Label                  matlab.ui.control.Label
        VIsualServoingButton      matlab.ui.control.Button
        CollisionDetectionButton  matlab.ui.control.Button
        RESUMEButton              matlab.ui.control.Button
        STARTButton               matlab.ui.control.Button
        ESTOPButton               matlab.ui.control.Button
        StatusLamp                matlab.ui.control.Lamp
        StatusLampLabel           matlab.ui.control.Label
        q7Slider                  matlab.ui.control.Slider
        q7SliderLabel             matlab.ui.control.Label
        q6Slider                  matlab.ui.control.Slider
        q6SliderLabel             matlab.ui.control.Label
        q5Slider                  matlab.ui.control.Slider
        q5SliderLabel             matlab.ui.control.Label
        q4Slider                  matlab.ui.control.Slider
        q4SliderLabel             matlab.ui.control.Label
        q3Slider                  matlab.ui.control.Slider
        q3SliderLabel             matlab.ui.control.Label
        q2Slider                  matlab.ui.control.Slider
        q2SliderLabel             matlab.ui.control.Label
        q1Slider                  matlab.ui.control.Slider
        q1SliderLabel             matlab.ui.control.Label
        JOGGINGLabel              matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        ZButton_2                 matlab.ui.control.Button
        ZButton                   matlab.ui.control.Button
        YButton_2                 matlab.ui.control.Button
        YButton                   matlab.ui.control.Button
        XButton_2                 matlab.ui.control.Button
        XButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        
        ModelUR3 %where i will load UR3
        
        
        UR3StartingQ = zeros(1,7); % Description
        Laps = 1 % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           PlaceObject("BenchTop1.ply",[0,0.5,0]);
           app.ModelUR3 = UR3robot
           app.ModelUR3.model.base = app.ModelUR3.model.base*transl([0,1.3,0.79]); % x z y move out of script if needed
           app.ModelUR3.model.animate(app.UR3StartingQ)


           axis equal
           drawnow()
           
        end

        % Callback function: UIFigure, UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            q = app.ModelUR3.model.getpos;
            tr = app.ModelUR3.model.fkine(q);
            tr(1,4) = tr(1,4) + 0.1;
            newQ = app.ModelUR3.model.ikcon(tr,q);
            app.ModelUR3.model.animate(newQ);
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
           q = app.ModelUR3.model.getpos;
            tr = app.ModelUR3.model.fkine(q);
            tr(1,4) = tr(1,4) - 0.1;
            newQ = app.ModelUR3.model.ikcon(tr,q);
            app.ModelUR3.model.animate(newQ); 
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            q = app.ModelUR3.model.getpos;
            tr = app.ModelUR3.model.fkine(q);
            tr(2,4) = tr(2,4) + 0.1;
            newQ = app.ModelUR3.model.ikcon(tr,q);
            app.ModelUR3.model.animate(newQ); 
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
             q = app.ModelUR3.model.getpos;
            tr = app.ModelUR3.model.fkine(q);
            tr(2,4) = tr(2,4) - 0.1;
            newQ = app.ModelUR3.model.ikcon(tr,q);
            app.ModelUR3.model.animate(newQ);
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            q = app.ModelUR3.model.getpos;
            tr = app.ModelUR3.model.fkine(q);
            tr(3,4) = tr(3,4) + 0.1;
            newQ = app.ModelUR3.model.ikcon(tr,q);
            app.ModelUR3.model.animate(newQ);
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            q = app.ModelUR3.model.getpos;
            tr = app.ModelUR3.model.fkine(q);
            tr(3,4) = tr(3,4) - 0.1;
            newQ = app.ModelUR3.model.ikcon(tr,q);
            app.ModelUR3.model.animate(newQ);
        end

        % Callback function: q1Slider, q1Slider
        function SliderValueChanged(app, event)
            value = app.q1Slider.Value;
            link1 = deg2rad(value);
            currentQ = app.ModelUR3.model.getpos();
            app.ModelUR3.model.animate([link1,currentQ(1,2),currentQ(1,3),currentQ(1,4),currentQ(1,5),currentQ(1,6),currentQ(1,7)]);


        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            value = app.q2Slider.Value;
            link2 = deg2rad(value);
            currentQ = app.ModelUR3.model.getpos();
            app.ModelUR3.model.animate([currentQ(1,1),link2,currentQ(1,3),currentQ(1,4),currentQ(1,5),currentQ(1,6),currentQ(1,7)]);
            
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            value = app.q3Slider.Value;
            link3 = deg2rad(value);
            currentQ = app.ModelUR3.model.getpos();
            app.ModelUR3.model.animate([currentQ(1,1),currentQ(1,2),link3,currentQ(1,4),currentQ(1,5),currentQ(1,6),currentQ(1,7)]);
        end

        % Value changed function: q4Slider
        function q4SliderValueChanged(app, event)
            value = app.q4Slider.Value;
            link4 = deg2rad(value);
            currentQ = app.ModelUR3.model.getpos();
            app.ModelUR3.model.animate([currentQ(1,1),currentQ(1,2),currentQ(1,3),link4,currentQ(1,5),currentQ(1,6),currentQ(1,7)]);
        end

        % Value changed function: q5Slider
        function q5SliderValueChanged(app, event)
            value = app.q5Slider.Value;
            link5 = deg2rad(value);
            currentQ = app.ModelUR3.model.getpos();
            app.ModelUR3.model.animate([currentQ(1,1),currentQ(1,2),currentQ(1,3),currentQ(1,4),link5,currentQ(1,6),currentQ(1,7)]);
        end

        % Value changed function: q6Slider
        function q6SliderValueChanged(app, event)
            value = app.q6Slider.Value;
            link6 = deg2rad(value);
            currentQ = app.ModelUR3.model.getpos();
            app.ModelUR3.model.animate([currentQ(1,1),currentQ(1,2),currentQ(1,3),currentQ(1,4),currentQ(1,5),link6,currentQ(1,7)]);
        end

        % Value changed function: q7Slider
        function q7SliderValueChanged(app, event)
            value = app.q7Slider.Value;
            link7 = deg2rad(value);
            currentQ = app.ModelUR3.model.getpos();
            app.ModelUR3.model.animate([currentQ(1,1),currentQ(1,2),currentQ(1,3),currentQ(1,4),currentQ(1,5),currentQ(1,6),link7]);
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            
            app.STARTButton.Enable = false
            app.q1Slider.Enable = false
            app.q2Slider.Enable = false
            app.q3Slider.Enable = false
            app.q4Slider.Enable = false
            app.q5Slider.Enable = false
            app.q6Slider.Enable = false
            app.q7Slider.Enable = false
            app.ESTOPButton.Enable = false
            app.ZButton.Enable = false
            app.ZButton_2.Enable = false
            app.YButton.Enable = false
            app.YButton_2.Enable = false
            app.XButton.Enable = false
            app.XButton_2.Enable = false
            app.StatusLamp.Color='red'
            app.VIsualServoingButton.Enable = false
            app.CollisionDetectionButton.Enable = false


            uiwait();
            
            
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
         while app.Laps < 10 
            drawnow
            
%             Stop = get(app.ESTOPButton);
%             
%             if Stop == 1
%                 disp("Stopped")
%             end
             
             q = app.ModelUR3.model.getpos();
        tr1 = transl([0.5,2,1.2]);
        tr2 = transl([ 0.1,1,1.5])
        q1 = app.ModelUR3.model.ikcon(tr1)
        q2 = app.ModelUR3.model.ikcon(tr2)
        steps = 300
        qMatrix = jtraj(q,q1,steps);
        for i = 1:steps;
            app.ModelUR3.model.animate(qMatrix(i,:))
            drawnow();
        end
        qMatrix = jtraj(q1,q2,steps)
        for i = 1:steps;
            app.ModelUR3.model.animate(qMatrix(i,:))
            drawnow();
        end
        
        
        app.Laps=app.Laps+1
end
        end

        % Button pushed function: RESUMEButton
        function RESUMEButtonPushed(app, event)
            fig = uifigure;
            selection = uiconfirm(fig,'If you wish to resume operation, please click OK','Resume')


            app.STARTButton.Enable = true
            app.q1Slider.Enable = true
            app.q2Slider.Enable = true
            app.q3Slider.Enable = true
            app.q4Slider.Enable = true
            app.q5Slider.Enable = true
            app.q6Slider.Enable = true
            app.q7Slider.Enable = true
            app.ESTOPButton.Enable = true
            app.ZButton.Enable = true
            app.ZButton_2.Enable = true
            app.YButton.Enable = true
            app.YButton_2.Enable = true
            app.XButton.Enable = true
            app.XButton_2.Enable = true
            app.VIsualServoingButton.Enable = true
            app.CollisionDetectionButton.Enable = true
            
            app.StatusLamp.Color='green'
            uiresume()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X+';
            app.XButton_2 = uibutton(app.UIFigure, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = 'X-';
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Y+';
            app.YButton_2 = uibutton(app.UIFigure, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = 'Y-';
            app.ZButton = uibutton(app.UIFigure, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = 'Z+';
            app.ZButton_2 = uibutton(app.UIFigure, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = 'Z-';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'ur3-300x263-1.png';
            app.JOGGINGLabel = uilabel(app.UIFigure);
            app.JOGGINGLabel.Text = 'JOGGING';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-360 360];
            app.q1Slider.MajorTicks = [-360 -240 -120 0 120 240 360];
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.q1Slider.Value = 2;
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [-360 360];
            app.q2Slider.MajorTicks = [-360 -240 -120 0 120 240 360];
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [-360 360];
            app.q3Slider.MajorTicks = [-360 -240 -120 0 120 240 360];
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.Limits = [-360 360];
            app.q4Slider.MajorTicks = [-360 -240 -120 0 120 240 360];
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @q4SliderValueChanged, true);
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.Limits = [-360 360];
            app.q5Slider.MajorTicks = [-360 -240 -120 0 120 240 360];
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @q5SliderValueChanged, true);
            app.q6SliderLabel = uilabel(app.UIFigure);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.UIFigure);
            app.q6Slider.Limits = [-360 360];
            app.q6Slider.MajorTicks = [-360 -240 -120 0 120 240 360];
            app.q6Slider.ValueChangedFcn = createCallbackFcn(app, @q6SliderValueChanged, true);
            app.q7SliderLabel = uilabel(app.UIFigure);
            app.q7SliderLabel.Text = 'q7';
            app.q7Slider = uislider(app.UIFigure);
            app.q7Slider.Limits = [-360 360];
            app.q7Slider.MajorTicks = [-360 -240 -120 0 120 240 360];
            app.q7Slider.ValueChangedFcn = createCallbackFcn(app, @q7SliderValueChanged, true);
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.RESUMEButton = uibutton(app.UIFigure, 'push');
            app.RESUMEButton.ButtonPushedFcn = createCallbackFcn(app, @RESUMEButtonPushed, true);
            app.RESUMEButton.Text = 'RESUME';
            app.CollisionDetectionButton = uibutton(app.UIFigure, 'push');
            app.CollisionDetectionButton.Text = 'Collision Detection';
            app.VIsualServoingButton = uibutton(app.UIFigure, 'push');
            app.VIsualServoingButton.Text = 'VIsual Servoing';
            app.UR3Label = uilabel(app.UIFigure);
            app.UR3Label.Text = 'UR3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UR3app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end